classdef TickTock_BeauVer1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        BeautyVerOneUIFigure  matlab.ui.Figure
        UIAxes                matlab.ui.control.UIAxes
        Button                matlab.ui.control.Button
        SysInfoLabel          matlab.ui.control.Label
        STARTLabel            matlab.ui.control.Label
        UIAxes2               matlab.ui.control.UIAxes
        UIAxes2_2             matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        % 更新时间柱
        Timer_01 = timer('StartDelay', 1, 'Period', 1,...
            'TasksToExecute', Inf, 'ExecutionMode', 'fixedRate');
        Button_Down = false; % 按钮状态
        
        % 时间统计
        StartTick = '';   % 计时器启动时间
        EndTick = '';     % 计时器结束时间
        ItemDuration = 0; % 持续时间 min
        ItemName char ;   % 事件
        ItemDetail char;  % 事件详情
        
        
    end
    
    methods (Access = private)
        
        function ImageBackGroundTab_01(app)
            try
                imshow('D:\Codes\MatlabFiles\Blocks\TickTockFiles\Wallpaper_97.jpg',...
                    'Parent',app.UIAxes);
            catch EM
                fprintf('error message:%s\n',EM.identifier);
            end
        end
        
        function UpdateSysInfo(app,info)
            if ischar(info)
                app.SysInfoLabel.Text = info;
            end
        end
        
        function InitStates(app)
            app.Button_Down = false;
        end
        
        % 时间柱
        function DrawDurationBar_01(app)
            temp = etime(datevec(datestr(datetime('now'),'yy/mm/dd  HH:MM:SS')),...
                datevec(app.StartTick));
            app.ItemDuration = int32(temp/1); % min
            
            b_1 = barh(app.UIAxes2,0.125,app.ItemDuration);
            % 设置柱状图
            b_1.FaceColor = [0.85 0.3 0.1];
            b_1.EdgeColor = [0.85 0.3 0.1];
            b_1.LineWidth = 1.2;
        end
        
        
        % 时间柱时间函数
        function UpdateTickBar(app)
            disp('time bar')
        end
        
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            disp('tick tock beauty version')
            ImageBackGroundTab_01(app)
            info_temp = 'tick tock beauty version';
            UpdateSysInfo(app,info_temp);
            
            % 启动时间柱
            app.Timer_01.TimerFcn = @(~, ~) DrawDurationBar_01(app);
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            value = app.Button_Down;
            State = {'START','STOP'};
            if value
                % 启动计时器状态中
                app.STARTLabel.Text = State{1};
                app.Button.BackgroundColor = [0.47,0.67,0.19];
                app.EndTick = datestr(datetime('now'),'yy/mm/dd  HH:MM:SS');
                
                if ~isempty(timerfind(app.Timer_01))
                    stop(app.Timer_01);
                end
            else
                app.STARTLabel.Text = State{2};
                app.Button.BackgroundColor = [0.64,0.08,0.18];
                
                % 停止计时器状态中
                app.ItemDuration = 0;
                
                app.StartTick = datestr(datetime('now'),'yy/mm/dd  HH:MM:SS');
                start(app.Timer_01);
            end
            app.Button_Down = ~value;
        end

        % Close request function: BeautyVerOneUIFigure
        function BeautyVerOneUIFigureCloseRequest(app, event)
            delete(app)
            delete(timerfindall())
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.BeautyVerOneUIFigure = uifigure;
            app.BeautyVerOneUIFigure.Name = 'BeautyVerOne';
            app.BeautyVerOneUIFigure.CloseRequestFcn = createCallbackFcn(app, @BeautyVerOneUIFigureCloseRequest, true);
            app.UIAxes = uiaxes(app.BeautyVerOneUIFigure);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes.YTickLabel = {'[ ]'};
            app.UIAxes.Visible = 'off';
            app.Button = uibutton(app.BeautyVerOneUIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Icon = 'power_button.png';
            app.Button.Text = '';
            app.SysInfoLabel = uilabel(app.BeautyVerOneUIFigure);
            app.SysInfoLabel.Text = 'SysInfo';
            app.STARTLabel = uilabel(app.BeautyVerOneUIFigure);
            app.STARTLabel.Text = 'START';
            app.UIAxes2 = uiaxes(app.BeautyVerOneUIFigure);
            app.UIAxes2.XLim = [0 240];
            app.UIAxes2.YLim = [0 0.5];
            app.UIAxes2.CLim = [0 0.5];
            app.UIAxes2.ALim = [0 0.5];
            app.UIAxes2.XTick = [0 30 60 90 120 150 180 210 240];
            app.UIAxes2.YTick = [];
            app.UIAxes2_2 = uiaxes(app.BeautyVerOneUIFigure);
            app.UIAxes2_2.XLim = [0 240];
            app.UIAxes2_2.YLim = [0 0.5];
            app.UIAxes2_2.CLim = [0 0.5];
            app.UIAxes2_2.ALim = [0 0.5];
            app.UIAxes2_2.XTick = [0 30 60 90 120 150 180 210 240];
            app.UIAxes2_2.YTick = [];
        end
    end

    methods (Access = public)

        % Construct app
        function app = TickTock_BeauVer1

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.BeautyVerOneUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.BeautyVerOneUIFigure)
        end
    end
end